classdef select_poles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatRROMAGUIUIFigure            matlab.ui.Figure
        StabilizationcriteriaPanel     matlab.ui.container.Panel
        FrequencydevEditField          matlab.ui.control.NumericEditField
        DampingdevEditField            matlab.ui.control.NumericEditField
        DampingdevLabel                matlab.ui.control.Label
        FrequencydevLabel              matlab.ui.control.Label
        Damping_min_limEditField       matlab.ui.control.NumericEditField
        Damping_max_limEditField       matlab.ui.control.NumericEditField
        MPDuperboundEditField_2Label   matlab.ui.control.Label
        PhidevEditField                matlab.ui.control.NumericEditField
        PhidevEditField_2Label         matlab.ui.control.Label
        TransfnormdevEditField         matlab.ui.control.NumericEditField
        TransfnormdevEditField_2Label  matlab.ui.control.Label
        TransfnormEditField            matlab.ui.control.NumericEditField
        TransfnormEditField_2Label     matlab.ui.control.Label
        DampingboundsLabel             matlab.ui.control.Label
        MPClowerboundEditField         matlab.ui.control.NumericEditField
        MPClowerboundEditField_2Label  matlab.ui.control.Label
        MPDupperboundEditField         matlab.ui.control.NumericEditField
        ShowallpolesCheckBox           matlab.ui.control.CheckBox
        ShowPSDCheckBox                matlab.ui.control.CheckBox
        CurrentpoleinformationPanel    matlab.ui.container.Panel
        Order_val                      matlab.ui.control.Label
        Freq_val                       matlab.ui.control.Label
        Damping_val                    matlab.ui.control.Label
        Transf_norm_val                matlab.ui.control.Label
        MPC_val                        matlab.ui.control.Label
        MPD_val                        matlab.ui.control.Label
        OrderLabel                     matlab.ui.control.Label
        FrequencyHzLabel               matlab.ui.control.Label
        DampingLabel                   matlab.ui.control.Label
        TransfernormLabel              matlab.ui.control.Label
        MPCLabel                       matlab.ui.control.Label
        MPDLabel                       matlab.ui.control.Label
        StabilizationdiagramPanel      matlab.ui.container.Panel
        Apply                          matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        ExpFigureButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Dyn_par % Description
    end


    properties (Access = private)
        dataarray
        clambdaarray
        warray
        xiarray
        phiarray
        Normarray
        MPCarray
        MPDarray
        SySOrd
        Sets
        rref
        sampling_rate
        ref_vect
        scal_fact
        PolesData
        mapping_array
        selected_poles_handle
        selected_indexes = [];
        hover_pole_handle
    end

    methods (Access = private)

        function updatediagram(app)
            freq_dev=app.FrequencydevEditField.Value;
            damp_dev= app.DampingdevEditField.Value;
            damp_low_lim=app.Damping_min_limEditField.Value;
            damp_up_lim=app.Damping_max_limEditField.Value;
            phi_dev=app.PhidevEditField.Value;
            norm=app.TransfnormEditField.Value;
            norm_dev=app.TransfnormdevEditField.Value;
            MPC_lim=app.MPClowerboundEditField.Value;
            MPD_lim=app.MPDupperboundEditField.Value;

            [stable_array, modelorder_array]=stable_poles_eval(app.clambdaarray,app.warray,app.xiarray,...
                app.phiarray,app.Normarray,app.MPCarray,app.MPDarray,app.SySOrd,freq_dev,damp_dev,damp_low_lim,...
                damp_up_lim,phi_dev,norm_dev,norm,MPC_lim,MPD_lim);

            [handle_stables, handle_unstables]=stab_diag(app.dataarray,app.warray,stable_array,modelorder_array,app.UIAxes,app.ShowPSDCheckBox.Value,app.ShowallpolesCheckBox.Value,...
                app.rref,app.Sets,app.sampling_rate,app.ref_vect);

            app.mapping_array=[[modelorder_array{:}]',[app.warray{:}]',[app.xiarray{:}]',[app.Normarray{:}]',[app.MPCarray{:}]',[app.MPDarray{:}]'];

            %Prepare the memory for the Hover
            app.PolesData.freq=[];
            app.PolesData.order=[];

            %Assign a click and save data for the STABLES
            if ~isempty(handle_stables) && isvalid(handle_stables)
                handle_stables.ButtonDownFcn=createCallbackFcn(app, @PoloClickeado, true);
                %Add their coordinates to the Hover list
                app.PolesData.freq=[app.PolesData.freq, handle_stables.XData(:)'];
                app.PolesData.order=[app.PolesData.order, handle_stables.YData(:)'];
            end

            %Assign a click and save data for the UNSTABLE items
            if ~isempty(handle_unstables) && isvalid(handle_unstables)
                handle_unstables.ButtonDownFcn = createCallbackFcn(app, @PoloClickeado, true);
                %Add their coordinates to the Hover list
                app.PolesData.freq=[app.PolesData.freq, handle_unstables.XData(:)'];
                app.PolesData.order=[app.PolesData.order, handle_unstables.YData(:)'];
            end
            app.UIAxes.ButtonDownFcn=@(src, event)app.PoloClickeado(src, event);
            app.selected_indexes=[];
            app.update_selection();
        end

        function windowButtonMotionFcn(app, ~)
            if isempty(app.PolesData) || isempty(app.PolesData.freq)
                return;
            end
            %Obtain current position of the mouse click
            currentPoint = app.UIAxes.CurrentPoint;
            mouseX = currentPoint(1,1);
            mouseY = currentPoint(1,2);

            %Check that the mouse is within the boundaries of the graph
            xLim = app.UIAxes.XLim;
            yLim = app.UIAxes.YLim;
            if mouseX>=xLim(1)&& mouseX<=xLim(2)&&mouseY>=yLim(1)&& mouseY<=yLim(2)

                tolX=0.02*(xLim(2)-xLim(1));
                tolY=0.02*(yLim(2)-yLim(1));

                % Normalized distance is obtained
                distance=sqrt( ((app.PolesData.freq - mouseX)./tolX).^2 + ...
                    ((app.PolesData.order - mouseY)./tolY).^2 );

                [minDist,idx]=min(distance);

                if minDist<1
                    %Update labels
                    idorder = find(app.mapping_array(:,2) == app.PolesData.freq(idx) & app.mapping_array(:,1) == app.PolesData.order(idx), 1);

                    app.Order_val.Text       = sprintf('%d', app.mapping_array(idorder,1));
                    app.Freq_val.Text        = sprintf('%.2f', app.mapping_array(idorder,2));
                    app.Damping_val.Text     = sprintf('%.2f', app.mapping_array(idorder,3));
                    app.Transf_norm_val.Text = sprintf('%1.2e', app.mapping_array(idorder,4)/app.scal_fact^2);
                    app.MPC_val.Text         = sprintf('%.2f', app.mapping_array(idorder,5));
                    app.MPD_val.Text         = sprintf('%.2f', app.mapping_array(idorder,6));

                    %Draw circle in the Hover
                    if isempty(app.hover_pole_handle)||~isvalid(app.hover_pole_handle)
                        hold(app.UIAxes, 'on');
                        app.hover_pole_handle=scatter(app.UIAxes, app.PolesData.freq(idx), app.PolesData.order(idx), ...
                            150, 'o', 'MarkerEdgeColor', [0.7 0.7 0.7], 'LineWidth', 1.5, 'HitTest', 'off');
                        hold(app.UIAxes, 'off');
                    else
                        app.hover_pole_handle.XData = app.PolesData.freq(idx);
                        app.hover_pole_handle.YData = app.PolesData.order(idx);
                        app.hover_pole_handle.Visible = 'on';
                    end

                else
                    %Clean labels if there are not close poles
                    app.Order_val.Text = '---';
                    app.Freq_val.Text = '---';
                    app.Damping_val.Text = '---';
                    app.Transf_norm_val.Text = '---';
                    app.MPC_val.Text = '---';
                    app.MPD_val.Text = '---';

                    %And clean the circle in the Hover
                    if ~isempty(app.hover_pole_handle) && isvalid(app.hover_pole_handle)
                        app.hover_pole_handle.Visible='off';
                    end
                end
            end
        end

        %CLICK: Select and Deselect Candidate Poles
        function PoloClickeado(app, ~, ~)
            %If there are no charged poles, ignore the click
            if isempty(app.PolesData) || isempty(app.PolesData.freq)
                return;
            end

            % Force the left axis to display the
            % Frequency and Order coordinates
            yyaxis(app.UIAxes,'left');

            %Capture the exact coordinate of the click
            axPoint=app.UIAxes.CurrentPoint;
            clickX=axPoint(1,1);
            clickY=axPoint(1,2);

            xLim = app.UIAxes.XLim;
            yLim = app.UIAxes.YLim;

            tolX=0.04*(xLim(2)-xLim(1));
            tolY=0.04*(yLim(2)-yLim(1));

            %The distance from our click to all existing poles is calculated
            dist=sqrt(((app.PolesData.freq-clickX)./tolX).^2+...
                ((app.PolesData.order-clickY)./tolY).^2 );

            %Find the closest pole
            [minDist,idxClicked]=min(dist);

            if minDist<1.5
                if ismember(idxClicked, app.selected_indexes)
                    %If it was already selected, it is removed
                    app.selected_indexes(app.selected_indexes==idxClicked) = [];
                else
                    %If it was not selected, it is selected
                    app.selected_indexes(end+1)=idxClicked;
                end
                %The red circles are drawn
                app.update_selection();
            end
        end

        % Auxiliary function to draw the red circles over the selected
        % poles
        function update_selection(app)
            if ~isempty(app.selected_poles_handle) && isvalid(app.selected_poles_handle)
                delete(app.selected_poles_handle);
            end

            if ~isempty(app.selected_indexes)
                yyaxis(app.UIAxes, 'left');

                hold(app.UIAxes, 'on');
                freqs_sel=app.PolesData.freq(app.selected_indexes);
                orders_sel=app.PolesData.order(app.selected_indexes);

                %Draw empty red circles over the selected points
                app.selected_poles_handle = scatter(app.UIAxes, freqs_sel, orders_sel, 120, 'ro', ...
                    'LineWidth', 1.8, 'HitTest', 'off');

                app.selected_poles_handle.ZData=0.5*ones(size(freqs_sel));

                hold(app.UIAxes, 'off');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, clambda, W, Xi, phi, Norm, MPC, MPD, SySOrd, Sets, rref, sampling_rate, ref_vect, scal_fact)
            app.dataarray=data;
            app.clambdaarray=clambda;
            app.warray=W;
            app.xiarray=Xi;
            app.phiarray=phi;
            app.Normarray=Norm;
            app.MPCarray=MPC;
            app.MPDarray=MPD;
            app.SySOrd=SySOrd;
            app.Sets=Sets;
            app.rref=rref;
            app.sampling_rate=sampling_rate;
            app.ref_vect=ref_vect;
            app.scal_fact=scal_fact;

            %====================%

            app.FrequencydevEditField.Value=1;
            app.DampingdevEditField.Value=5;
            app.Damping_min_limEditField.Value=0;
            app.Damping_max_limEditField.Value=20;
            app.PhidevEditField.Value=1;
            app.TransfnormEditField.Value=50;
            app.TransfnormdevEditField.Value=50;
            app.MPClowerboundEditField.Value=0.85;
            app.MPDupperboundEditField.Value=70;
            %====================%

            app.updatediagram();
            app.MatRROMAGUIUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @windowButtonMotionFcn, true);
            uiwait(app.MatRROMAGUIUIFigure);
        end

        % Button pushed function: Apply
        function ApplyButtonPushed(app, event)
            app.updatediagram();
        end

        % Button pushed function: ExpFigureButton
        function ExpFigureButtonPushed(app, event)
            %Open the save menu and prompt the user to enter a name for a .pdf file
            [file, path]=uiputfile('*.pdf','Save as...');
            if isequal(file, 0) || isequal(path,0)
                return;
            end
            %Build the full path where the file will be saved
            fullFilePath=fullfile(path,file);

            %Save the graph (app.UIAxes)
            try
                exportgraphics(app.UIAxes,fullFilePath,'ContentType', 'vector');
            catch ME
                %If an error occurs, display the alert
                uialert(app.MatRROMAGUIUIFigure,['Error: ',ME.message]);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %Check if no poles are selected
            if isempty(app.selected_indexes)
                msg = 'No poles have been selected. Do you want to abort the poles selection process?';
                title = 'Warning: No selected poles';

                %Display the dialog box in the UI
                selection=uiconfirm(app.MatRROMAGUIUIFigure, msg, title, ...
                    'Icon', 'warning', ...
                    'Options', {'Yes','No'}, ...
                    'DefaultOption',2, ...
                    'CancelOption',2);
                %Check the user's response
                if strcmp(selection, 'Yes')
                    delete(app);
                else
                    return;
                end
            else
                % Number of selected poles
                sel_id=length(app.selected_indexes);

                [~,order]=sort(app.PolesData.freq(app.selected_indexes));
                app.selected_indexes=app.selected_indexes(order);

                % Pre-allocation
                app.Dyn_par.order=zeros(1,sel_id);
                app.Dyn_par.freq=zeros(1,sel_id);
                app.Dyn_par.xi=zeros(1,sel_id);
                app.Dyn_par.MPC=zeros(1,sel_id);
                app.Dyn_par.MPD=zeros(1,sel_id);
                app.Dyn_par.phi=zeros(size(app.phiarray{1},1),sel_id);

                for i=1:sel_id
                    %Each parameter of the selected pole is assigned to its corresponding field in Dyn_par
                    curr_idx = app.selected_indexes(i);
                    idx_map = find(app.mapping_array(:,2)==app.PolesData.freq(curr_idx)&app.mapping_array(:,1)==app.PolesData.order(curr_idx),1);
                    idord=find(app.SySOrd==app.PolesData.order(curr_idx),1);
                    idphi = find(app.warray{idord}==app.PolesData.freq(curr_idx), 1);

                    app.Dyn_par.order(i)=app.mapping_array(idx_map, 1);
                    app.Dyn_par.freq(i)=app.mapping_array(idx_map, 2);
                    app.Dyn_par.xi(i)=app.mapping_array(idx_map, 3);
                    app.Dyn_par.phi(:,i)=app.phiarray{idord}(:,idphi);
                    app.Dyn_par.MPC(i)=app.mapping_array(idx_map, 5);
                    app.Dyn_par.MPD(i)=app.mapping_array(idx_map, 6);
                end
            end

            uiresume(app.MatRROMAGUIUIFigure);
        end

        % Close request function: MatRROMAGUIUIFigure
        function MatRROMAGUIUIFigureCloseRequest(app, event)
            selection = uiconfirm(app.MatRROMAGUIUIFigure, ...
                'Are you sure you want to exit?',...
                'Confirm Close', ...
                'Icon', 'question', ...
                'Options', {'Yes', 'No'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);

            %Check the user's response
            if strcmp(selection, 'Yes')
                if isempty(app.Dyn_par)
                    app.Dyn_par = [];
                end
                uiresume(app.MatRROMAGUIUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatRROMAGUIUIFigure = uifigure('Visible', 'off');
            app.MatRROMAGUIUIFigure.Name = 'MatRROMA GUI';
            app.MatRROMAGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @MatRROMAGUIUIFigureCloseRequest, true);
            app.StabilizationdiagramPanel = uipanel(app.MatRROMAGUIUIFigure);
            app.StabilizationdiagramPanel.Title = 'Stabilization diagram';
            app.UIAxes = uiaxes(app.StabilizationdiagramPanel);
            xlabel(app.UIAxes, 'Frequency [Hz]')
            ylabel(app.UIAxes, 'Model Order')
            zlabel(app.UIAxes, 'Z')
            app.ExpFigureButton = uibutton(app.StabilizationdiagramPanel, 'push');
            app.ExpFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExpFigureButtonPushed, true);
            app.ExpFigureButton.Text = 'Exp. Figure';
            app.SaveButton = uibutton(app.StabilizationdiagramPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Apply = uibutton(app.StabilizationdiagramPanel, 'push');
            app.Apply.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.Apply.Text = 'Apply changes';
            app.CurrentpoleinformationPanel = uipanel(app.MatRROMAGUIUIFigure);
            app.CurrentpoleinformationPanel.Title = 'Current pole information';
            app.MPDLabel = uilabel(app.CurrentpoleinformationPanel);
            app.MPDLabel.Text = 'MPD:';
            app.MPCLabel = uilabel(app.CurrentpoleinformationPanel);
            app.MPCLabel.Text = 'MPC:';
            app.TransfernormLabel = uilabel(app.CurrentpoleinformationPanel);
            app.TransfernormLabel.Text = 'Transfer norm:';
            app.DampingLabel = uilabel(app.CurrentpoleinformationPanel);
            app.DampingLabel.Text = 'Damping [%]:';
            app.FrequencyHzLabel = uilabel(app.CurrentpoleinformationPanel);
            app.FrequencyHzLabel.Text = 'Frequency [Hz]:';
            app.OrderLabel = uilabel(app.CurrentpoleinformationPanel);
            app.OrderLabel.Text = 'Order:';
            app.MPD_val = uilabel(app.CurrentpoleinformationPanel);
            app.MPC_val = uilabel(app.CurrentpoleinformationPanel);
            app.Transf_norm_val = uilabel(app.CurrentpoleinformationPanel);
            app.Damping_val = uilabel(app.CurrentpoleinformationPanel);
            app.Freq_val = uilabel(app.CurrentpoleinformationPanel);
            app.Order_val = uilabel(app.CurrentpoleinformationPanel);
            app.StabilizationcriteriaPanel = uipanel(app.MatRROMAGUIUIFigure);
            app.StabilizationcriteriaPanel.Title = 'Stabilization criteria';
            app.ShowPSDCheckBox = uicheckbox(app.StabilizationcriteriaPanel);
            app.ShowPSDCheckBox.Text = 'Show PSD';
            app.ShowallpolesCheckBox = uicheckbox(app.StabilizationcriteriaPanel);
            app.ShowallpolesCheckBox.Text = 'Show all poles';
            app.MPDupperboundEditField = uieditfield(app.StabilizationcriteriaPanel, 'numeric');
            app.MPClowerboundEditField_2Label = uilabel(app.StabilizationcriteriaPanel);
            app.MPClowerboundEditField_2Label.Text = 'MPC lower bound';
            app.MPClowerboundEditField = uieditfield(app.StabilizationcriteriaPanel, 'numeric');
            app.DampingboundsLabel = uilabel(app.StabilizationcriteriaPanel);
            app.DampingboundsLabel.Text = 'Damping bounds [%]';
            app.TransfnormEditField_2Label = uilabel(app.StabilizationcriteriaPanel);
            app.TransfnormEditField_2Label.Text = 'Transf. norm';
            app.TransfnormEditField = uieditfield(app.StabilizationcriteriaPanel, 'numeric');
            app.TransfnormdevEditField_2Label = uilabel(app.StabilizationcriteriaPanel);
            app.TransfnormdevEditField_2Label.Text = 'Transf. norm dev. [%]';
            app.TransfnormdevEditField = uieditfield(app.StabilizationcriteriaPanel, 'numeric');
            app.PhidevEditField_2Label = uilabel(app.StabilizationcriteriaPanel);
            app.PhidevEditField_2Label.Text = 'Phi dev. [%]';
            app.PhidevEditField = uieditfield(app.StabilizationcriteriaPanel, 'numeric');
            app.MPDuperboundEditField_2Label = uilabel(app.StabilizationcriteriaPanel);
            app.MPDuperboundEditField_2Label.Text = 'MPD uper bound [º]';
            app.Damping_max_limEditField = uieditfield(app.StabilizationcriteriaPanel, 'numeric');
            app.Damping_min_limEditField = uieditfield(app.StabilizationcriteriaPanel, 'numeric');
            app.FrequencydevLabel = uilabel(app.StabilizationcriteriaPanel);
            app.FrequencydevLabel.Text = 'Frequency dev. [%]';
            app.DampingdevLabel = uilabel(app.StabilizationcriteriaPanel);
            app.DampingdevLabel.Text = 'Damping dev. [%]';
            app.DampingdevEditField = uieditfield(app.StabilizationcriteriaPanel, 'numeric');
            app.FrequencydevEditField = uieditfield(app.StabilizationcriteriaPanel, 'numeric');
            % Show the figure after all components are created
            app.MatRROMAGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = select_poles(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MatRROMAGUIUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MatRROMAGUIUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatRROMAGUIUIFigure)
        end
    end
end